classdef calibrate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        IntensityUnitSelect         matlab.ui.control.DropDown
        IntensityunitDropDownLabel  matlab.ui.control.Label
        Explanation                 matlab.ui.control.Label
        Table                       matlab.ui.control.Table
        SaveButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties(SetAccess = protected)
        Parent
    end

    methods (Access = public)
        
        function res = wait(app)
            uiwait(app.UIFigure);

            res = [];
        end

        function showValue(app,val)
            app.Parent.Display.fill(val);
            app.Parent.Display.flip();
        end

    end

    properties(Hidden,Access=protected)
        LuminanceFn = @rfstim.display.calibrate.luminance;
    end

    methods(Static)
        function I = luminance(y, ~)
            I = y;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.Parent = parent;

            app.Table.ColumnFormat = {'logical','numeric','numeric'};

            val = parent.Display.Brightness.Value;
            lumen = parent.Display.Brightness.Luminance;

            if numel(val) == 2
                val = round((0:0.1:1)*255);
                maxlumen = lumen(2);
                minlumen = lumen(1);
                deltalum = maxlumen-minlumen;
                lumen = (minlumen:0.1*deltalum:maxlumen);
            end

            active = false(size(val))';

            data = cell(numel(val),3);
            for i = 1:numel(val)
                data{i,1} = active(i);
                data{i,2} = val(i);
                data{i,3} = lumen(i);
            end

            app.Table.Data = data;

            plot(app.UIAxes, val, lumen);           
        end

        % Cell edit callback: Table
        function TableCellEdit(app, event)
            indices = event.Indices;
            % newData = event.NewData;
            
            switch indices(2)
                case 1
                    % only one is active
                    for i = 1:size(app.Table.Data,1)
                        app.Table.Data{i,1} = false;
                    end
                    app.Table.Data{indices(1),1} = true;
                    app.showValue(app.Table.Data{indices(1),2});

                case 3
                    data = cell2mat(app.Table.Data(:,2:3));
                    plot(app.UIAxes, data(:,1), rfstim.display.calibrate.LuminanceFn(data(:,2), app.Parent.Display));
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.Parent.Display.Brightness.Value = cell2mat(app.Table.Data(:,2));
            app.Parent.Display.Brightness.Luminance = cell2mat(app.Table.Data(:,3));
            app.Parent.Display.save();
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.Parent.Display.Brightness.Value = cell2mat(app.Table.Data(:,2));
            app.Parent.Display.Brightness.Luminance = ...
                rfstim.display.calibrate.LuminanceFn(cell2mat(app.Table.Data(:,3), app.Parent.Display));
            app.Parent.Display.save(true);
        end

        % Value changed function: IntensityUnitSelect
        function IntensityUnitSelectValueChanged(app, event)
            value = app.IntensityUnitSelect.Value;
            % FIXME
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'V (1)')
            ylabel(app.UIAxes, 'I (cd/m²)')
            zlabel(app.UIAxes, 'Z')
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save...';
            app.Table = uitable(app.GridLayout);
            app.Table.ColumnName = {'Active'; 'Value'; 'Intensity'};
            app.Table.RowName = {};
            app.Table.ColumnEditable = [true false true];
            app.Table.CellEditCallback = createCallbackFcn(app, @TableCellEdit, true);
            app.Explanation = uilabel(app.GridLayout);
            app.Explanation.Interpreter = 'html';
            app.Explanation.Text = {'<b>Luminance calibration</b>'; ''; 'Activate each row individually, measure the luminance and update the third table column accordingly.'; ''; 'When finished, press the <i>Done</i> button. Alternatively you may save it as an INI file using the "Save..." button.'};
            app.IntensityunitDropDownLabel = uilabel(app.GridLayout);
            app.IntensityunitDropDownLabel.Text = 'Intensity unit:';
            app.IntensityUnitSelect = uidropdown(app.GridLayout);
            app.IntensityUnitSelect.Items = {'cd/mm²'};
            app.IntensityUnitSelect.ValueChangedFcn = createCallbackFcn(app, @IntensityUnitSelectValueChanged, true);
            app.IntensityUnitSelect.Value = 'cd/mm²';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibrate(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end