classdef calibrate < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        PanelCheckerboard     matlab.ui.container.Panel
        PanelCorners          matlab.ui.container.Panel
        PanelCenter           matlab.ui.container.Panel
        GridLayoutCenter      matlab.ui.container.GridLayout
        AcceptButton          matlab.ui.control.Button
        ButtonCenter          matlab.ui.control.Button
        ButtonXMinus          matlab.ui.control.Button
        ButtonXPlus           matlab.ui.control.Button
        ButtonYMinus          matlab.ui.control.Button
        ButtonYPlus           matlab.ui.control.Button
        CheckerboardCheckBox  matlab.ui.control.CheckBox
        CornersCheckBox       matlab.ui.control.CheckBox
        CenterCheckBox        matlab.ui.control.CheckBox
    end

    
    properties(SetAccess = protected)
        App

        What = ''           % Currently active "what to do".

        Pixel = [1 1]       % Pixel size (mm).
        Resolution = [1 1]  % Monitor resolution (px).
        Dimensions = [1 1]  % Monitor dimensions (mm).

        Subject = [0 0 0]   % Subject position (mm).

        Texture = []
    end

    methods (Static)
        function [label,order] = name()
            label = 'Calibration';
            order = 0;
        end
    end
    
    methods (Access = public)
        
        function startup(comp,app) %#ok<*INUSD,*MANU>
            app.Stimulus = struct(name='Calibration');
            comp.App = app;           
            comp.select('center')
        end

        function run(comp)
        end

        function pause(comp)
        end

        function change(comp)
            comp.Dimensions = comp.App.Monitor.dimension;
            comp.Resolution = comp.App.Monitor.resolution;
            comp.Pixel = comp.Dimensions ./ comp.Resolution;
            comp.Subject = comp.App.Subject.position;
            comp.draw()
        end            
    end

    methods(Access=protected)

        function select(comp, what)
            switch(what)
                case 'center'
                    comp.PanelCenter.Enable = true;
                    comp.CenterCheckBox.Value = true;
                    comp.PanelCorners.Enable = false;
                    comp.CornersCheckBox.Value = false;
                    comp.PanelCheckerboard.Enable = false;
                    comp.CheckerboardCheckBox.Value = false;
                case 'corners'
                    comp.PanelCenter.Enable = false;
                    comp.CenterCheckBox.Value = false;
                    comp.PanelCorners.Enable = true;
                    comp.CornersCheckBox.Value = true;
                    comp.PanelCheckerboard.Enable = false;
                    comp.CheckerboardCheckBox.Value = false;
                case 'checkerboard'
                    comp.PanelCenter.Enable = false;
                    comp.CenterCheckBox.Value = false;
                    comp.PanelCorners.Enable = false;
                    comp.CornersCheckBox.Value = false;
                    comp.PanelCheckerboard.Enable = true;
                    comp.CheckerboardCheckBox.Value = true;
            end

            if ~strcmp(what,comp.What)
                comp.What = what;
                comp.change();
            end
        end

        function draw(comp)
            switch (comp.What)
                case 'center'
                    res = comp.Resolution - [1 1];
                    point = round([comp.Subject(1),-comp.Subject(2)] ./ comp.Pixel + (res/2));
                    point = min(max(point,[0 0]),res);
                    comp.Subject(1:2) = (point - res/2 - [0.5 0.5]) .* comp.Pixel .* [1 -1];
        
                    wptr = comp.App.WindowHandle;
                    if isempty(wptr)
                        return
                    end
        
                    Screen('DrawLines', wptr, [point(1) point(1) 0 res(1); 0 res(2) point(2) point(2)]);
                    Screen('Flip', wptr);

                otherwise
                    %FIXME
            end
        end
    end
       
    methods(Access=private)
        function d = delta(comp)
            %DELTA          Get offset to move.

            [keyIsDown, ~, keyCode, ~] = KbCheck;
            if keyIsDown
                if keyCode(51)
                    d = [10 10];
                elseif keyCode(38)
                    d = [1 1];
                end
            else
                d = comp.Pixel;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CenterCheckBox
        function CenterCheckBoxValueChanged(comp, event)
            comp.select('center')
        end

        % Value changed function: CornersCheckBox
        function CornersCheckBoxValueChanged(comp, event)
            comp.select('corners')
        end

        % Value changed function: CheckerboardCheckBox
        function CheckerboardCheckBoxValueChanged(comp, event)
            comp.select('checkerboard')
        end

        % Button pushed function: ButtonCenter
        function ButtonCenterPushed(comp, event)
            comp.Subject(1:2) = [0 0];
            comp.draw();
        end

        % Button pushed function: ButtonXPlus
        function ButtonXPlusPushed(comp, event)
            d = comp.delta();
            comp.Subject(1) = comp.Subject(1) + d(1);
            comp.draw();
        end

        % Button pushed function: ButtonXMinus
        function ButtonXMinusPushed(comp, event)
            d = comp.delta();
            comp.Subject(1) = comp.Subject(1) - d(1);
            comp.draw();
        end

        % Button pushed function: ButtonYPlus
        function ButtonYPlusPushed(comp, event)
            d = comp.delta();
            comp.Subject(2) = comp.Subject(2) + d(2);
            comp.draw();
        end

        % Button pushed function: ButtonYMinus
        function ButtonYMinusPushed(comp, event)
            d = comp.delta();
            comp.Subject(2) = comp.Subject(2) - d(2);
            comp.draw();
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(comp, event)
            comp.App.setSubjectPosition(comp.Subject);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 718 660];
            comp.GridLayout = uigridlayout(comp);
            comp.CenterCheckBox = uicheckbox(comp.GridLayout);
            comp.CenterCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CenterCheckBoxValueChanged, true);
            comp.CenterCheckBox.Text = 'Center';
            comp.CenterCheckBox.Value = true;
            comp.CornersCheckBox = uicheckbox(comp.GridLayout);
            comp.CornersCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CornersCheckBoxValueChanged, true);
            comp.CornersCheckBox.Text = 'Corners';
            comp.CheckerboardCheckBox = uicheckbox(comp.GridLayout);
            comp.CheckerboardCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckerboardCheckBoxValueChanged, true);
            comp.CheckerboardCheckBox.Text = 'Checker board';
            comp.PanelCenter = uipanel(comp.GridLayout);
            comp.GridLayoutCenter = uigridlayout(comp.PanelCenter);
            comp.ButtonYPlus = uibutton(comp.GridLayoutCenter, 'push');
            comp.ButtonYPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonYPlusPushed, true);
            comp.ButtonYPlus.Text = '▲';
            comp.ButtonYMinus = uibutton(comp.GridLayoutCenter, 'push');
            comp.ButtonYMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonYMinusPushed, true);
            comp.ButtonYMinus.Text = '▼';
            comp.ButtonXPlus = uibutton(comp.GridLayoutCenter, 'push');
            comp.ButtonXPlus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonXPlusPushed, true);
            comp.ButtonXPlus.Text = '▶';
            comp.ButtonXMinus = uibutton(comp.GridLayoutCenter, 'push');
            comp.ButtonXMinus.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonXMinusPushed, true);
            comp.ButtonXMinus.Text = '◀';
            comp.ButtonCenter = uibutton(comp.GridLayoutCenter, 'push');
            comp.ButtonCenter.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonCenterPushed, true);
            comp.ButtonCenter.Text = '◆';
            comp.AcceptButton = uibutton(comp.GridLayoutCenter, 'push');
            comp.AcceptButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AcceptButtonPushed, true);
            comp.AcceptButton.Text = 'Accept';
            comp.PanelCorners = uipanel(comp.GridLayout);
            comp.PanelCorners.Enable = 'off';
            comp.PanelCheckerboard = uipanel(comp.GridLayout);
            comp.PanelCheckerboard.Enable = 'off';
        end
    end
end