classdef moving_grating < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                 matlab.ui.container.GridLayout
        InitializeButton           matlab.ui.control.Button
        UsedivisioningCheckBox     matlab.ui.control.CheckBox
        DivisioningPanel           matlab.ui.container.Panel
        ParameterPanel             matlab.ui.container.Panel
        GridLayoutParameters       matlab.ui.container.GridLayout
        EditDuration               matlab.ui.control.NumericEditField
        LabelPauseUnit             matlab.ui.control.Label
        EditPause                  matlab.ui.control.NumericEditField
        PauseLabel                 matlab.ui.control.Label
        LabelDurationUnit          matlab.ui.control.Label
        EditCycles                 matlab.ui.control.NumericEditField
        CyclesLabel                matlab.ui.control.Label
        LabelHelp                  matlab.ui.control.Label
        CheckBoxRandomizeEach      matlab.ui.control.CheckBox
        EditRepetitions            matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel  matlab.ui.control.Label
        CheckBoxRandomize          matlab.ui.control.CheckBox
        LabelAnglesUnit            matlab.ui.control.Label
        EditAngles                 matlab.ui.control.EditField
        EditAngleCount             matlab.ui.control.NumericEditField
        AnglecountEditFieldLabel   matlab.ui.control.Label
        CheckBoxPerspective        matlab.ui.control.CheckBox
        LabelExternal              matlab.ui.control.Label
        LabelSpeedUnit             matlab.ui.control.Label
        LabelRetinaSpeedUnit       matlab.ui.control.Label
        EditRetinaSpeed            matlab.ui.control.NumericEditField
        LabelRetina                matlab.ui.control.Label
        EditSpeed                  matlab.ui.control.NumericEditField
        SpeedEditFieldLabel        matlab.ui.control.Label
        EditRetinaSize             matlab.ui.control.NumericEditField
        EditSize                   matlab.ui.control.NumericEditField
        BarsizeEditFieldLabel      matlab.ui.control.Label
        LabelRetinaSizeUnit        matlab.ui.control.Label
        LabelSizeUnit              matlab.ui.control.Label
        LabelContrastUnit          matlab.ui.control.Label
        EditContrast               matlab.ui.control.NumericEditField
        ContrastEditFieldLabel     matlab.ui.control.Label
        LabelBackgroundValues      matlab.ui.control.Label
        EditBackground             matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel   matlab.ui.control.Label
    end

    properties (Access = private)
        App % Parent application
    end
    
    
    properties(SetAccess=protected,GetAccess=public)
        Data
        Initialized = true
    end

    methods (Static)
        function [label,order] = name()
            label = 'Moving grating';
            order = 10;
        end
    end
    
    methods (Access = public)
        
        function startup(comp,app)
            % FIXME data here or in main app?
            app.Stimulus = struct(name='Calibration');
            comp.Data = struct(...
                name='moving grating',...
                config = struct( ...
                    size_deg = 20,...
                    speed_deg_per_s = 20),...
                time = struct(),...
                data = struct());
            comp.App = app;
            comp.updateRetina();
        end
        
        function res = modified(comp,value)
            if nargin > 1
                comp.Initialized = ~value;

                % FIXME
            end

            res = ~comp.Initialized;
        end

        function run(comp)
            comp.App.Stimulus = comp.Data;
            % FIXME
        end
        
        function pause(comp)
        end
        
        function change(comp)
        end
    end
    
    methods (Access = protected)

        function updateData(comp)
            % FIXME
        end

        function updateExternal(comp)
            f = comp.App.Subject.focal_length;

            comp.Data.config.size_deg = comp.Data.config.size_retina_um/(1000*f*pi)*180;
            comp.EditSize.Value = comp.Data.config.size_deg;

            comp.Data.config.speed_deg_per_s = comp.Data.config.speed_retina_um_per_s/(1000*f*pi)*180;
            comp.EditSpeed.Value = comp.Data.config.speed_deg_per_s;
        end

        function updateRetina(comp)
            f = comp.App.Subject.focal_length;

            comp.Data.config.size_retina_um = round(1000*comp.Data.config.size_deg*f * pi/180);
            comp.EditRetinaSize.Value = comp.Data.config.size_retina_um;

            comp.Data.config.speed_retina_um_per_s = round(1000*comp.Data.config.speed_deg_per_s*f * pi/180);
            comp.EditRetinaSpeed.Value = comp.Data.config.speed_retina_um_per_s;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditBackground
        function EditBackgroundValueChanged(comp, event)
            comp.Data.config.background = comp.EditBackground.Value;
            comp.modified(true);
        end

        % Value changed function: EditContrast
        function EditContrastValueChanged(comp, event)
            comp.Data.config.background = comp.EditContrast.Value;
            comp.modified(true);
        end

        % Value changed function: EditSize
        function EditSizeValueChanged(comp, event)
            comp.Data.config.size_deg = comp.EditSize.Value;
            comp.updateRetina()
            comp.modified(true);
        end

        % Value changed function: EditSpeed
        function EditSpeedValueChanged(comp, event)
            comp.Data.config.speed_deg_per_s = comp.EditSpeed.Value;
            comp.updateRetina()
            comp.modified(true);            
        end

        % Value changed function: EditRetinaSize
        function EditRetinaSizeValueChanged(comp, event)
            comp.Data.config.size_retina_um = comp.EditRetinaSize.Value;
            comp.updateExternal()
            comp.modified(true);   
        end

        % Value changed function: EditRetinaSpeed
        function EditRetinaSpeedValueChanged(comp, event)
            comp.Data.config.speed_retina_um_per_s = comp.EditRetinaSpeed.Value;
            comp.updateExternal()
            comp.modified(true);               
        end

        % Value changed function: EditCycles
        function EditCyclesValueChanged(comp, event)
            value = comp.EditCycles.Value;
            comp.EditDuration.Value = comp.EditSize.Value/comp.EditSpeed.Value * value;
        end

        % Value changed function: EditPause
        function EditPauseValueChanged(comp, event)
            value = comp.EditPause.Value;
            
        end

        % Value changed function: EditAngleCount
        function EditAngleCountValueChanged(comp, event)
            value = comp.EditAngleCount.Value;
            step = 180/value;
            ang = (0:step:180-step);
            comp.EditAngles.Value = strjoin(strtrim(cellstr(num2str(ang'))),',');
            comp.EditAnglesValueChanged([]);
        end

        % Value changed function: EditAngles
        function EditAnglesValueChanged(comp, event)
            value = comp.EditAngles.Value;
            % FIXME
        end

        % Value changed function: CheckBoxRandomize
        function CheckBoxRandomizeValueChanged(comp, event)
            value = comp.CheckBoxRandomize.Value;
            
        end

        % Value changed function: EditRepetitions
        function EditRepetitionsValueChanged(comp, event)
            value = comp.EditRepetitions.Value;
            
        end

        % Value changed function: CheckBoxRandomizeEach
        function CheckBoxRandomizeEachValueChanged(comp, event)
            value = comp.CheckBoxRandomizeEach.Value;
            
        end

        % Value changed function: CheckBoxPerspective
        function CheckBoxPerspectiveValueChanged(comp, event)
            value = comp.CheckBoxPerspective.Value;
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 718 660];
            comp.GridLayout = uigridlayout(comp);
            comp.ParameterPanel = uipanel(comp.GridLayout);
            comp.ParameterPanel.Title = 'Parameters:';
            comp.GridLayoutParameters = uigridlayout(comp.ParameterPanel);
            comp.BackgroundEditFieldLabel = uilabel(comp.GridLayoutParameters);
            comp.BackgroundEditFieldLabel.Text = 'Background:';
            comp.EditBackground = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditBackground.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditBackgroundValueChanged, true);
            comp.EditBackground.Value = 50;
            comp.LabelBackgroundValues = uilabel(comp.GridLayoutParameters);
            comp.LabelBackgroundValues.Text = '%';
            comp.ContrastEditFieldLabel = uilabel(comp.GridLayoutParameters);
            comp.ContrastEditFieldLabel.Text = 'Contrast:';
            comp.EditContrast = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditContrast.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditContrastValueChanged, true);
            comp.EditContrast.Value = 80;
            comp.LabelContrastUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelContrastUnit.Text = '%';
            comp.LabelSizeUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelSizeUnit.Text = '°';
            comp.LabelRetinaSizeUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelRetinaSizeUnit.Text = 'µm';
            comp.BarsizeEditFieldLabel = uilabel(comp.GridLayoutParameters);
            comp.BarsizeEditFieldLabel.Text = 'Bar size:';
            comp.EditSize = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditSize.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditSizeValueChanged, true);
            comp.EditSize.Value = 20;
            comp.EditRetinaSize = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditRetinaSize.ValueDisplayFormat = '%.1f';
            comp.EditRetinaSize.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditRetinaSizeValueChanged, true);
            comp.SpeedEditFieldLabel = uilabel(comp.GridLayoutParameters);
            comp.SpeedEditFieldLabel.Text = 'Speed:';
            comp.EditSpeed = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditSpeed.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditSpeedValueChanged, true);
            comp.EditSpeed.Value = 20;
            comp.LabelRetina = uilabel(comp.GridLayoutParameters);
            comp.LabelRetina.Text = 'Retina (estamate)';
            comp.EditRetinaSpeed = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditRetinaSpeed.ValueDisplayFormat = '%.1f';
            comp.EditRetinaSpeed.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditRetinaSpeedValueChanged, true);
            comp.LabelRetinaSpeedUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelRetinaSpeedUnit.Text = 'µm/s';
            comp.LabelSpeedUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelSpeedUnit.Text = '°/s';
            comp.LabelExternal = uilabel(comp.GridLayoutParameters);
            comp.LabelExternal.Text = 'External:';
            comp.CheckBoxPerspective = uicheckbox(comp.GridLayoutParameters);
            comp.CheckBoxPerspective.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBoxPerspectiveValueChanged, true);
            comp.CheckBoxPerspective.Text = 'Correct perspective';
            comp.CheckBoxPerspective.Value = true;
            comp.AnglecountEditFieldLabel = uilabel(comp.GridLayoutParameters);
            comp.AnglecountEditFieldLabel.Text = 'Angle count:';
            comp.EditAngleCount = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditAngleCount.Limits = [1 360];
            comp.EditAngleCount.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditAngleCountValueChanged, true);
            comp.EditAngleCount.Value = 4;
            comp.EditAngles = uieditfield(comp.GridLayoutParameters, 'text');
            comp.EditAngles.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditAnglesValueChanged, true);
            comp.EditAngles.Value = '0,45,90,135';
            comp.LabelAnglesUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelAnglesUnit.Text = '°';
            comp.CheckBoxRandomize = uicheckbox(comp.GridLayoutParameters);
            comp.CheckBoxRandomize.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBoxRandomizeValueChanged, true);
            comp.CheckBoxRandomize.Text = 'Randomize';
            comp.CheckBoxRandomize.Value = true;
            comp.RepetitionsEditFieldLabel = uilabel(comp.GridLayoutParameters);
            comp.RepetitionsEditFieldLabel.Text = 'Repetitions:';
            comp.EditRepetitions = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditRepetitions.Limits = [1 Inf];
            comp.EditRepetitions.ValueDisplayFormat = '%d';
            comp.EditRepetitions.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditRepetitionsValueChanged, true);
            comp.EditRepetitions.Value = 1;
            comp.CheckBoxRandomizeEach = uicheckbox(comp.GridLayoutParameters);
            comp.CheckBoxRandomizeEach.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CheckBoxRandomizeEachValueChanged, true);
            comp.CheckBoxRandomizeEach.Text = 'Randomize each repetition';
            comp.CheckBoxRandomizeEach.Value = true;
            comp.LabelHelp = uilabel(comp.GridLayoutParameters);
            comp.LabelHelp.Text = 'FIXME';
            comp.CyclesLabel = uilabel(comp.GridLayoutParameters);
            comp.CyclesLabel.Text = 'Cycles:';
            comp.EditCycles = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditCycles.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditCyclesValueChanged, true);
            comp.EditCycles.Value = 2;
            comp.LabelDurationUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelDurationUnit.Text = 's';
            comp.PauseLabel = uilabel(comp.GridLayoutParameters);
            comp.PauseLabel.Text = 'Pause:';
            comp.EditPause = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditPause.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @EditPauseValueChanged, true);
            comp.EditPause.Value = 1;
            comp.LabelPauseUnit = uilabel(comp.GridLayoutParameters);
            comp.LabelPauseUnit.Text = 's';
            comp.EditDuration = uieditfield(comp.GridLayoutParameters, 'numeric');
            comp.EditDuration.Editable = 'off';
            comp.EditDuration.Value = 2;
            comp.DivisioningPanel = uipanel(comp.GridLayout);
            comp.DivisioningPanel.Enable = 'off';
            comp.DivisioningPanel.Title = 'Divisioning';
            comp.UsedivisioningCheckBox = uicheckbox(comp.GridLayout);
            comp.UsedivisioningCheckBox.Text = 'Use divisioning';
            comp.InitializeButton = uibutton(comp.GridLayout, 'push');
            comp.InitializeButton.Text = 'Initialize';
        end
    end
end