classdef bboteensy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CancelButton             matlab.ui.control.Button
        TestLabel                matlab.ui.control.Label
        OffButton                matlab.ui.control.Button
        OnButton                 matlab.ui.control.Button
        OkButton                 matlab.ui.control.Button
        ConnectButton            matlab.ui.control.Button
        Lamp                     matlab.ui.control.Lamp
        SerialportDropDown       matlab.ui.control.DropDown
        SerialportDropDownLabel  matlab.ui.control.Label
    end

    
    properties (SetAccess = private)
        Port            
        Module          
        Result = false
    end
    
    methods (Access = public)
        
        function wait(app)
            uiwait(app.UIFigure);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, module, position)
            % arguments            
            if nargin < 2
                module = rfstim.sync.bboteensy([]);
            end
            if nargin < 3
                position = groot.ScreenSize;
            end
            app.Module = module;
            
            % position
            app.UIFigure.Position(1:2) = position(1:2) + floor((position(3:4)-app.UIFigure.Position(3:4))/2);

            % setup UI
            if ispc
                items = serialportlist;
            else
                items = {};

                files = [dir('/dev/ttyACM*');dir('/dev/ttyUSB*')];
                for i = 1:numel(files)
                    items(end+1) = ['/dev/' files(i).name]; %#ok<AGROW>
                end
            end

            app.SerialportDropDown.Items = items;
            if ~isempty(items)
                app.SerialportDropDown.Value = module.Config.get('port', items{1});
                app.SerialportDropDownValueChanged([]);
            end
        end

        % Value changed function: SerialportDropDown
        function SerialportDropDownValueChanged(app, event)
            app.Port = app.SerialportDropDown.Value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.Module.open(app.Port);
            if app.Module.good()
                app.Lamp.Color = [0.0,1.0,0.0];
            else
                app.Lamp.Color = [1.0,0.0,0.0];
            end
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            app.Module.on();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Result = false;
            uiresume(app.UIFigure);
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.Result = true;
            uiresume(app.UIFigure);  
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            app.Module.off();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SerialportDropDownLabel = uilabel(app.UIFigure);
            app.SerialportDropDownLabel.Text = 'Serial port:';
            app.SerialportDropDown = uidropdown(app.UIFigure);
            app.SerialportDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialportDropDownValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.OnButton = uibutton(app.UIFigure, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Text = 'On';
            app.OffButton = uibutton(app.UIFigure, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Text = 'Off';
            app.TestLabel = uilabel(app.UIFigure);
            app.TestLabel.Text = 'Test:';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bboteensy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end