classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LayoutMain               matlab.ui.container.GridLayout
        LayoutGrid               matlab.ui.container.GridLayout
        ModulePanel              matlab.ui.container.Panel
        ModuleSelectLayout       matlab.ui.container.GridLayout
        ModuleStopButton         matlab.ui.control.Button
        ModuleSelect             matlab.ui.control.DropDown
        ModuleLabel              matlab.ui.control.Label
        ModuleRunButton          matlab.ui.control.Button
        PathLayout               matlab.ui.container.GridLayout
        PathLabel                matlab.ui.control.Label
        ChangePathButton         matlab.ui.control.Button
        StatusLabel              matlab.ui.control.Label
        LayoutProperties         matlab.ui.container.GridLayout
        SynchronizationPanel     matlab.ui.container.Panel
        ScreenSync               matlab.ui.control.DropDown
        SyncLabel                matlab.ui.control.Label
        ScreenSyncConnected      matlab.ui.control.Lamp
        SubjectPanel             matlab.ui.container.Panel
        SubjectTagEdit           matlab.ui.control.EditField
        TagEditFieldLabel        matlab.ui.control.Label
        SubjectPositionXEdit     matlab.ui.control.NumericEditField
        XmmEditFieldLabel        matlab.ui.control.Label
        SubjectPositionZEdit     matlab.ui.control.NumericEditField
        ZmmLabel                 matlab.ui.control.Label
        SubjectPositionYEdit     matlab.ui.control.NumericEditField
        YmmLabel                 matlab.ui.control.Label
        SubjectCenteredCheckBox  matlab.ui.control.CheckBox
        SubjectPositionLabel     matlab.ui.control.Label
        SubjectFOVInfo           matlab.ui.control.Label
        SubjectFocalLengthEdit   matlab.ui.control.NumericEditField
        FocallengthmmLabel       matlab.ui.control.Label
        SubjectSelect            matlab.ui.control.DropDown
        ScreenPanel              matlab.ui.container.Panel
        CalibrateButton          matlab.ui.control.Button
        ScreenNoSelect           matlab.ui.control.DropDown
        IDLabel                  matlab.ui.control.Label
        ConnectedLamp            matlab.ui.control.Lamp
        ConnectButton            matlab.ui.control.Button
        DisplayYpxEdit           matlab.ui.control.NumericEditField
        DisplayXpxEdit           matlab.ui.control.NumericEditField
        XYpxLabel                matlab.ui.control.Label
        DisplayXmmEdit           matlab.ui.control.NumericEditField
        XYmmEditFieldLabel       matlab.ui.control.Label
        DisplayYmmEdit           matlab.ui.control.NumericEditField
        ScreenAddButton          matlab.ui.control.Button
        ScreenSelect             matlab.ui.control.DropDown
        DisplayGraph             matlab.ui.control.UIAxes
    end


    properties(Access=private)
        UIEnableHandles
        UIEnableStatus
    end
  
    % === UI
    methods

        function uiEnableAll(app,value)
            %enableUI       En- or disable the user interface.
            %
            %   
            
            if value
                if isempty(app.UIEnableStatus)
                    return
                end

                % restore "enabled" state
                for i = 1:length(app.UIEnableHandles)
                    app.UIEnableHandles{i}.Enable = app.UIEnableStatus{i};
                end

                app.UIEnableStatus = [];
            else
                % memorize all objects that have a "Enable" field
                allChildren = findobj(app.UIFigure);
                
                for i = 1:length(allChildren)
                    if isprop(allChildren(i), 'Enable')
                        app.UIEnableHandles{end+1} = allChildren(i);
                    end
                end

                % disable all objects with "Enable" field
                app.UIEnableStatus = cell(1, length(allChildren));
                for i = 1:length(app.UIEnableHandles)
                    app.UIEnableStatus{i} = app.UIEnableHandles{i}.Enable;
                    app.UIEnableHandles{i}.Enable = false;
                end
            end
        end
        
        function h = disable(app)
            app.uiEnableAll(false);
            h = onCleanup(@() app.uiEnableAll(true));
        end

        function enableRun(app,value)
            app.ModuleRunButton.Enable = value;
            app.ModuleStopButton.Enable = value;            
        end

    end
    
    properties(SetAccess = protected)
        Config                          % Application configuration.
        Workdir = '.'                   % Working directory (where files are saved).

        Display = rfstim.display        % The stimulus display screen.
        Connected = false               % PTB is connected.

        Subject                         % The subject (or animal).

        Sync                            % Sync module.
        
        Stimulus                        % Stimulus representation.
    end

    % === Display and screen.
    methods
        
        function startupDisplay(app)
            arguments
                app rfstim.app
            end

            dispname = app.Config.get('display', 'Generic');

            app.ScreenSelect.Items = rfstim.display.find();
            app.ScreenSelect.Value = dispname;

            app.selectDisplay(dispname);
        end

        function selectDisplay(app,name)
            arguments
                app rfstim.app
                name string
            end

            try 
                app.Display = rfstim.display(Name=name);

                % update components
                app.DisplayXmmEdit.Value = app.Display.Dimension(1);
                app.DisplayYmmEdit.Value = app.Display.Dimension(2);
                app.DisplayXpxEdit.Value = app.Display.Resolution(1);
                app.DisplayYpxEdit.Value = app.Display.Resolution(2);
                app.ScreenSelect.Value = name;

                app.updateDisplayGraph();
                app.Config.set('display', name);
            catch
                % load previous
                app.selectDisplay(app.Config.get('display', 'Generic'));
                return
            end

            app.setSubjectPosition();
            app.resetStimulus();
        end

        function updateDisplayGraph(app)
            arguments
                app rfstim.app
            end

            x = app.Display.Brightness.Value;
            y = app.Display.Brightness.Luminance;

            plot(app.DisplayGraph, x, y);
            xlim(app.DisplayGraph, [min(x) max(x)]);
        end

        function startupScreen(app)
            arguments
                app rfstim.app
            end

            screens = Screen('Screens');
            screenitems = cell(1,numel(screens));
            for i = 1:numel(screens)
                screenitems{i} = num2str(screens(i));
            end

            app.ScreenNoSelect.Items = screenitems;
            app.ScreenNoSelect.Value = app.Config.get('screen.id', screenitems{1});
        end

        function selectScreen(app,id)
            arguments
                app rfstim.app
                id
            end

            app.ScreenNoSelect.Value = id;
            app.Config.set('screen.id', id);
        end

        function openScreen(app,id)
            arguments
                app rfstim.app
                id {mustBeInteger}
            end

            app.Connected = app.Display.open(id);
            
            switch app.Connected
                case true
                    app.ConnectedLamp.Color = [0.0,1.0,0.0];
                case false
                    app.ConnectedLamp.Color = [1.0,0.0,0.0];
            end
        end

        function closeScreen(app)
            arguments
                app rfstim.app
            end

            app.Display.close();
        end

    end

    % === Subject.
    methods

        function startupSubject(app)
            arguments
                app rfstim.app
            end
            subj = app.Config.get('subject.name', 'Generic');

            app.SubjectSelect.Items = rfstim.subject.find();
            app.SubjectSelect.Value = subj;

            app.selectSubject(subj);
        end

        function selectSubject(app,name)
            arguments
                app rfstim.app
                name string = ""
            end

            if ~isempty(name)
                try 
                    app.Subject = rfstim.subject(Name=name);
                    app.SubjectSelect.Value = name;
    
                    app.Config.set('subject.name', name);
                catch
                    app.selectSubject(app.Config.get('subject.name', 'Generic'));
                    return
                end
            end

            app.setSubjectPosition(app.Config.get('subject.position', [0 0 200]));
            app.setSubjectFocalLength(app.Subject.Eye.FocalLength);
        end

        function setSubjectPosition(app,pos)
            arguments
                app rfstim.app
                pos {mustBeNumeric} = []
            end

            if ~isempty(pos)
                if ~all(pos(1:2) == 0.0)
                    app.SubjectCenteredCheckBox.Value = false;
                    app.SubjectPositionXEdit.Enable = true;
                    app.SubjectPositionYEdit.Enable = true;
                end

                app.SubjectPositionXEdit.Value = pos(1);
                app.SubjectPositionYEdit.Value = pos(2);
                app.SubjectPositionZEdit.Value = pos(3);
            else
                pos = [...
                    app.SubjectPositionXEdit.Value, ...
                    app.SubjectPositionYEdit.Value, ...
                    app.SubjectPositionZEdit.Value];
            end

            app.Subject.Position = pos;
            app.Config.set('subject.position', pos);

            app.setSubjectFocalLength();
        end

        function setSubjectFocalLength(app,f)
            arguments
                app rfstim.app
                f {mustBeNumeric} = []
            end

            if isempty(f)
                f = app.Subject.Eye.FocalLength;
            else
                app.Subject.Eye.FocalLength = f;
            end
            app.SubjectFocalLengthEdit.Value = f;

            % info
            try
                pos = app.Subject.Position;
    
                dim = app.Display.Dimension;
                hangle = atan2(dim(1)/2+pos(1),pos(3))+atan2(dim(1)/2-pos(1),pos(3));
                vangle = atan2(dim(2)/2+pos(2),pos(3))+atan2(dim(2)/2-pos(2),pos(3));
                deg = pi/180;
    
                infotxt = sprintf('FOV: %.1f° × %.1f°\n         %.0f µm × %.0f µm (estimated)',...
                    hangle/deg,vangle/deg,...
                    f*hangle*1000,f*vangle*1000);
    
                app.SubjectFOVInfo.Text = infotxt;            
            catch 
            end

            app.resetStimulus();
        end

        function setSubjectTag(app,tag)
            arguments
                app rfstim.app
                tag string
            end

            app.Subject.Tag = tag;
            app.resetStimulus();
        end
    end

    % === Sync
    properties(Access = private)
        SyncModules
    end
        
    methods

        function startupSync(app)
            arguments
                app rfstim.app
            end

            appdir = mfilename('fullpath');
            [appdir,~,~] = fileparts(appdir);
            syncdir = fullfile(appdir, '+sync');

            app.SyncModules = rfstim.util.modules(syncdir);
            app.ScreenSync.Items = {app.SyncModules.label};

            app.selectSync();
        end

        function selectSync(app,name)
            arguments
                app rfstim.app
                name string = []
            end

            if isempty(name)
                name = app.Config.get('sync.name', app.ScreenSync.Items{1});
            end

            good = false;
            try 
                idx = find(strcmp({app.SyncModules.label},name));
                app.Sync = rfstim.sync.(app.SyncModules(idx).module)(app);

                onreturn = app.disable(); %#ok<NASGU>

                app.Sync.startup();
                good = app.Sync.good();

                if good
                    app.Config.set('sync.name', app.ScreenSync.Value);
                end
            catch me
                disp(getReport(me))
            end
               
            if good
                app.ScreenSyncConnected.Color = [0.0,1.0,0.0];
            else
                app.ScreenSyncConnected.Color = [1.0,0.0,0.0];
            end

            app.ScreenSync.Value = name;
        end

    end

    % === Stimulus
    properties(Access = private)
        StimulusModules
    end

    methods
        function startupSimulus(app)
            arguments
                app rfstim.app
            end

            appdir = mfilename('fullpath');
            [appdir,~,~] = fileparts(appdir);
            stimdir = fullfile(appdir, '+stimulus');

            app.StimulusModules = rfstim.util.modules(stimdir);
            app.ModuleSelect.Items = {app.StimulusModules.label};

            app.selectStimulus();
        end

        function resetStimulus(app)
            arguments
                app rfstim.app
            end

            try 
                app.enableRun(false);
                app.Stimulus.reset();
            catch 
                % ignored
            end
        end

        function selectStimulus(app,name)
            arguments
                app rfstim.app
                name string = []
            end

            if isempty(name)
                name = app.Config.get('stimulus.name', app.ModuleSelect.Items{1});
            end

            try 
                idx = find(strcmp({app.StimulusModules.label},name));
                app.Stimulus = rfstim.stimulus.(app.StimulusModules(idx).module)(app);

                onreturn = app.disable(); %#ok<NASGU>

                app.Stimulus.startup();
                app.Config.set('stimulus.name', name);
            catch me
                disp(getReport(me))
                % app.selectStimulus(app.ModuleSelect.Items{1})
            end            
        end        
    end

    % === Status
    methods

        function status(app, fmt, varargin)
            if nargin == 1
                app.StatusLabel.Text = '';
                return
            end

            try 
                app.StatusLabel.Text = sprintf(fmt, varargin{:});
            catch me
                disp(getReport(me))
            end
        end
            
    end

    % === Run/Stop logic
    methods
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Config = appconfig(Program = 'matlab-rfstim', File = 'global.mat');
            app.startupSubject(); % must happend before initDisplay !
            app.startupDisplay();
            app.startupScreen();
            app.startupSync();
            app.startupSimulus();

            app.Workdir = app.Config.get('workdir', pwd);
            app.PathLabel.Text = ['Path: ' app.Workdir];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: ScreenSelect
        function ScreenSelectValueChanged(app, event)
            app.selectDisplay(app.ScreenSelect.Value);
        end

        % Button pushed function: ScreenAddButton
        function ScreenAddButtonPushed(app, event)
            % FIXME
        end

        % Value changed function: ScreenNoSelect
        function ScreenNoSelectValueChanged(app, event)
            app.selectScreen(app.ScreenNoSelect.Value);           
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.openScreen(str2double(app.ScreenNoSelect.Value));
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            onclose = app.disable(); %#ok<NASGU>

            cal = rfstim.display.calibrate(app);
            cal.wait()

            app.updateDisplayGraph();
        end

        % Value changed function: SubjectSelect
        function SubjectSelectValueChanged(app, event)
            app.selectSubject(app.SubjectSelect.Value);            
        end

        % Value changed function: SubjectTagEdit
        function SubjectTagEditValueChanged(app, event)
            app.setSubjectTag(app.SubjectTagEdit.Value);
        end

        % Value changed function: SubjectFocalLengthEdit
        function SubjectFocalLengthEditValueChanged(app, event)
            app.setSubjectFocalLength(app.SubjectFocalLengthEdit.Value);
        end

        % Value changed function: SubjectCenteredCheckBox
        function SubjectCenteredCheckBoxValueChanged(app, event)
            if app.SubjectCenteredCheckBox.Value
                app.setSubjectPosition([0 0 app.Subject.Position(3)]);
                app.SubjectPositionXEdit.Enable = false;
                app.SubjectPositionYEdit.Enable = false;              
            else
                app.SubjectPositionXEdit.Enable = true;
                app.SubjectPositionYEdit.Enable = true;              
            end
        end

        % Value changed function: SubjectPositionXEdit
        function SubjectPositionXEditValueChanged(app, event)
            app.setSubjectPosition(); 
        end

        % Value changed function: SubjectPositionYEdit
        function SubjectPositionYEditValueChanged(app, event)
            app.setSubjectPosition();            
        end

        % Value changed function: SubjectPositionZEdit
        function SubjectPositionZEditValueChanged(app, event)
            app.setSubjectPosition();            
        end

        % Value changed function: ScreenSync
        function ScreenSyncValueChanged(app, event)
            app.selectSync(app.ScreenSync.Value);            
        end

        % Button pushed function: ChangePathButton
        function ChangePathButtonPushed(app, event)
            workdir = uigetdir(app.Workdir, 'Select work-directory...');

            if isnumeric(workdir)
                return
            end

            app.Workdir = workdir;
            app.PathLabel.Text = ['Path: ' app.Workdir];
            app.Config.set('workdir', app.Workdir);
        end

        % Value changed function: ModuleSelect
        function ModuleSelectValueChanged(app, event)
            app.selectStimulus(app.ModuleSelect.Value);
        end

        % Button pushed function: ModuleRunButton
        function ModuleRunButtonPushed(app, event)
            app.Stimulus.run();
        end

        % Button pushed function: ModuleStopButton
        function ModuleStopButtonPushed(app, event)
            app.Stimulus.stop();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LayoutMain = uigridlayout(app.UIFigure);
            app.LayoutProperties = uigridlayout(app.LayoutMain);
            app.ScreenPanel = uipanel(app.LayoutProperties);
            app.ScreenPanel.Title = 'Screen';
            app.DisplayGraph = uiaxes(app.ScreenPanel);
            xlabel(app.DisplayGraph, 'V (1)')
            ylabel(app.DisplayGraph, 'I (cd/m²)')
            zlabel(app.DisplayGraph, 'Z')
            app.ScreenSelect = uidropdown(app.ScreenPanel);
            app.ScreenSelect.Items = {};
            app.ScreenSelect.ValueChangedFcn = createCallbackFcn(app, @ScreenSelectValueChanged, true);
            app.ScreenSelect.Value = {};
            app.ScreenAddButton = uibutton(app.ScreenPanel, 'push');
            app.ScreenAddButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenAddButtonPushed, true);
            app.ScreenAddButton.Enable = 'off';
            app.ScreenAddButton.Text = 'Add...';
            app.DisplayYmmEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayYmmEdit.Editable = 'off';
            app.XYmmEditFieldLabel = uilabel(app.ScreenPanel);
            app.XYmmEditFieldLabel.Text = 'X,Y (mm):';
            app.DisplayXmmEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayXmmEdit.Editable = 'off';
            app.XYpxLabel = uilabel(app.ScreenPanel);
            app.XYpxLabel.Text = 'X,Y (px):';
            app.DisplayXpxEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayXpxEdit.Editable = 'off';
            app.DisplayYpxEdit = uieditfield(app.ScreenPanel, 'numeric');
            app.DisplayYpxEdit.Editable = 'off';
            app.ConnectButton = uibutton(app.ScreenPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectedLamp = uilamp(app.ScreenPanel);
            app.IDLabel = uilabel(app.ScreenPanel);
            app.IDLabel.Text = 'ID:';
            app.ScreenNoSelect = uidropdown(app.ScreenPanel);
            app.ScreenNoSelect.Items = {'''0''', '''1''', '''2'''};
            app.ScreenNoSelect.ValueChangedFcn = createCallbackFcn(app, @ScreenNoSelectValueChanged, true);
            app.ScreenNoSelect.Value = '''1''';
            app.CalibrateButton = uibutton(app.ScreenPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate...';
            app.SubjectPanel = uipanel(app.LayoutProperties);
            app.SubjectPanel.Title = 'Subject';
            app.SubjectSelect = uidropdown(app.SubjectPanel);
            app.SubjectSelect.Items = {'Generic'};
            app.SubjectSelect.ValueChangedFcn = createCallbackFcn(app, @SubjectSelectValueChanged, true);
            app.SubjectSelect.Value = 'Generic';
            app.FocallengthmmLabel = uilabel(app.SubjectPanel);
            app.FocallengthmmLabel.Text = 'Focal length (mm):';
            app.SubjectFocalLengthEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectFocalLengthEdit.Limits = [0 Inf];
            app.SubjectFocalLengthEdit.ValueDisplayFormat = '%.1f';
            app.SubjectFocalLengthEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectFocalLengthEditValueChanged, true);
            app.SubjectFOVInfo = uilabel(app.SubjectPanel);
            app.SubjectFOVInfo.Text = {'FOV: 111.8° × 79.4°'; '         5072 µm × 3603 µm (estimated)'};
            app.SubjectPositionLabel = uilabel(app.SubjectPanel);
            app.SubjectPositionLabel.Text = 'Postion (relative to monitor center):';
            app.SubjectCenteredCheckBox = uicheckbox(app.SubjectPanel);
            app.SubjectCenteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubjectCenteredCheckBoxValueChanged, true);
            app.SubjectCenteredCheckBox.Text = 'centered';
            app.SubjectCenteredCheckBox.Value = true;
            app.YmmLabel = uilabel(app.SubjectPanel);
            app.YmmLabel.Text = 'Y (mm):';
            app.SubjectPositionYEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionYEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionYEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectPositionYEditValueChanged, true);
            app.SubjectPositionYEdit.Enable = 'off';
            app.ZmmLabel = uilabel(app.SubjectPanel);
            app.ZmmLabel.Text = 'Z (mm):';
            app.SubjectPositionZEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionZEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionZEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectPositionZEditValueChanged, true);
            app.SubjectPositionZEdit.Value = 200;
            app.XmmEditFieldLabel = uilabel(app.SubjectPanel);
            app.XmmEditFieldLabel.Text = 'X (mm):';
            app.SubjectPositionXEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionXEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionXEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectPositionXEditValueChanged, true);
            app.SubjectPositionXEdit.Enable = 'off';
            app.TagEditFieldLabel = uilabel(app.SubjectPanel);
            app.TagEditFieldLabel.Text = 'Tag:';
            app.SubjectTagEdit = uieditfield(app.SubjectPanel, 'text');
            app.SubjectTagEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectTagEditValueChanged, true);
            app.SynchronizationPanel = uipanel(app.LayoutProperties);
            app.SynchronizationPanel.Title = 'Synchronization';
            app.ScreenSyncConnected = uilamp(app.SynchronizationPanel);
            app.SyncLabel = uilabel(app.SynchronizationPanel);
            app.SyncLabel.Text = 'Module:';
            app.ScreenSync = uidropdown(app.SynchronizationPanel);
            app.ScreenSync.Items = {};
            app.ScreenSync.ValueChangedFcn = createCallbackFcn(app, @ScreenSyncValueChanged, true);
            app.ScreenSync.Value = {};
            app.LayoutGrid = uigridlayout(app.LayoutMain);
            app.StatusLabel = uilabel(app.LayoutGrid);
            app.StatusLabel.Text = '';
            app.PathLayout = uigridlayout(app.LayoutGrid);
            app.ChangePathButton = uibutton(app.PathLayout, 'push');
            app.ChangePathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePathButtonPushed, true);
            app.ChangePathButton.Text = 'Change...';
            app.PathLabel = uilabel(app.PathLayout);
            app.PathLabel.Text = 'Path: .';
            app.ModuleSelectLayout = uigridlayout(app.LayoutGrid);
            app.ModuleRunButton = uibutton(app.ModuleSelectLayout, 'push');
            app.ModuleRunButton.ButtonPushedFcn = createCallbackFcn(app, @ModuleRunButtonPushed, true);
            app.ModuleRunButton.Icon = fullfile(pathToMLAPP, 'icons', 'breeze-dark', 'media-playback-start.svg');
            app.ModuleRunButton.Enable = 'off';
            app.ModuleRunButton.Text = 'Run';
            app.ModuleLabel = uilabel(app.ModuleSelectLayout);
            app.ModuleLabel.Text = 'Module:';
            app.ModuleSelect = uidropdown(app.ModuleSelectLayout);
            app.ModuleSelect.Items = {};
            app.ModuleSelect.ValueChangedFcn = createCallbackFcn(app, @ModuleSelectValueChanged, true);
            app.ModuleSelect.Value = {};
            app.ModuleStopButton = uibutton(app.ModuleSelectLayout, 'push');
            app.ModuleStopButton.ButtonPushedFcn = createCallbackFcn(app, @ModuleStopButtonPushed, true);
            app.ModuleStopButton.Icon = fullfile(pathToMLAPP, 'icons', 'breeze-dark', 'media-playback-stop.svg');
            app.ModuleStopButton.Enable = 'off';
            app.ModuleStopButton.Text = 'Stop';
            app.ModulePanel = uipanel(app.LayoutGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end