classdef oldapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LayoutMain               matlab.ui.container.GridLayout
        LayoutStimulus           matlab.ui.container.GridLayout
        SaveLayout               matlab.ui.container.GridLayout
        StatusLabel              matlab.ui.control.Label
        ChangeWorkdirButton      matlab.ui.control.Button
        StimulusSelectPanel      matlab.ui.container.Panel
        RunButton                matlab.ui.control.Button
        StimulusSelect           matlab.ui.control.DropDown
        StimulusLabel            matlab.ui.control.Label
        StimulusPanel            matlab.ui.container.Panel
        LayoutProperties         matlab.ui.container.GridLayout
        SyncPanel                matlab.ui.container.Panel
        ScreenSyncConnected      matlab.ui.control.Lamp
        ScreenSync               matlab.ui.control.DropDown
        SyncLabel                matlab.ui.control.Label
        SubjectPanel             matlab.ui.container.Panel
        SubjectInfo              matlab.ui.control.Label
        SubjectFOVInfo           matlab.ui.control.Label
        SubjectPositionZEdit     matlab.ui.control.NumericEditField
        ZmmLabel                 matlab.ui.control.Label
        SubjectPositionYEdit     matlab.ui.control.NumericEditField
        YmmLabel                 matlab.ui.control.Label
        SubjectPositionXEdit     matlab.ui.control.NumericEditField
        XmmEditFieldLabel        matlab.ui.control.Label
        SubjectCenteredCheckBox  matlab.ui.control.CheckBox
        SubjectPositionLabel     matlab.ui.control.Label
        SubjectFocalLength       matlab.ui.control.NumericEditField
        FocallengthmmLabel       matlab.ui.control.Label
        SubjectAdd               matlab.ui.control.Button
        SubjectSelect            matlab.ui.control.DropDown
        MonitorPanel             matlab.ui.container.Panel
        ConnectedLamp            matlab.ui.control.Lamp
        ConnectButton            matlab.ui.control.Button
        ScreenSelect             matlab.ui.control.DropDown
        ScreenDropDownLabel      matlab.ui.control.Label
        MonitorCalibrateButton   matlab.ui.control.Button
        MonitorYpxEditField      matlab.ui.control.NumericEditField
        MonitorXpxEditField      matlab.ui.control.NumericEditField
        XYpxEditFieldLabel       matlab.ui.control.Label
        MonitorYmmEditField      matlab.ui.control.NumericEditField
        MonitorXmmEditField      matlab.ui.control.NumericEditField
        XYmmEditFieldLabel       matlab.ui.control.Label
        MonitorAdd               matlab.ui.control.Button
        MonitorSelect            matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties(Access=public)
        Workdir             % Working directory (where to save files).

        Monitor = struct()  % Monitor data.
        Sync = struct()     % Synchronization data.
        Subject = struct()  % Subject (or animal) data.
        Stimulus = struct() % Stimulus data.
    end

    properties (GetAccess = public, SetAccess = protected)
        Config              % Configuration file (application config).

        StimulusModules     % List of stimulus modules
        StimulusComponents  % Loaded Stimulus handles (FIXME unused).
        StimulusHandle      % Current selected stimulus handle.

        SyncModules         % List of available sync modules.
        SyncHandle          % Sync module handle.

        WindowHandle = []   % Pointer to PTB window.
        WindowRect = []     % Window dimensions (from PTB).
        WindowClose = []    % Helper to close PTB window on exit.
    end
    
    properties(Access = private,Hidden)
        UIEnable = {}       % UI elements with 'Enable' property.
        EnableStatus = {}   % Saved 'Enable' status.
    end

    methods (Access = private)

        function saveData(app)
            %saveData       Save data after a stimulus run (in Workdir).
            rfstim = struct(...
                monitor = app.Monitor,...
                sync = app.Sync,...
                subject = app.Subject,...
                stimulus = app.Stimulus);

            t = datetime('now');
            fname = sprintf('%s/rfstim-%s.mat', app.Workdir, datestr(t,'yyyymmddTHHMMss'));
   
            save(fname, "rfstim", '-v7.3');
        end

        function enableUI(app,value)
            if value
                if isempty(app.UIEnable)
                    return
                end

                for i = 1:length(app.UIEnable)
                    app.UIEnable{i}.Enable = app.EnableStatus{i};
                end
            else
                if isempty(app.UIEnable)
                    allChildren = findobj(app.UIFigure);
                    
                    for i = 1:length(allChildren)
                        if isprop(allChildren(i), 'Enable')
                            app.UIEnable{end+1} = allChildren(i);
                        end
                    end

                    app.EnableStatus = cell(1, length(allChildren));
                end

                for i = 1:length(app.UIEnable)
                    app.EnableStatus{i} = app.UIEnable{i}.Enable;
                    app.UIEnable{i}.Enable = false;
                end
            end
        end
       
        function syncStatus(app,good)
            if good
                app.ScreenSyncConnected.Color = [0.0,1.0,0.0];
            else
                app.ScreenSyncConnected.Color = [1.0,0.0,0.0];
            end
        end
       
        function syncInit(app)
            good = false;
            try 
                app.SyncHandle.startup(app.Config);
                good = app.SyncHandle.good();

                if good
                    app.Config.set('sync.name', app.ScreenSync.Value);
                end
            catch me
                disp(me)
            end
               
            app.syncStatus(good);
        end
        
        function updateMonitorInfo(app, monitor)
            % FIXME load from file
            monitor = struct( ...
                name = monitor, ...
                dimension = [590.4,332.1], ...
                resolution = [2560,1440],...
                intensity = struct(...
                    value = [0 255],...
                    luminance = [0 250]) ...
                );
            
            app.Monitor = monitor;

            app.MonitorXmmEditField.Value = monitor.dimension(1);
            app.MonitorYmmEditField.Value = monitor.dimension(2);
            app.MonitorXpxEditField.Value = monitor.resolution(1);
            app.MonitorYpxEditField.Value = monitor.resolution(2);

            plot(app.UIAxes, monitor.intensity.value, monitor.intensity.luminance);
            xlim(app.UIAxes, [0 255]);
            grid(app.UIAxes, 'on');

            app.updateSubjectInfo()
        end

        function loadSubjectInfo(app,name)
            % FIXME load from file
            subject = struct(...
                name = name,...
                focal_length = app.SubjectFocalLength.Value,...
                position = [app.SubjectPositionXEdit.Value,...
                    app.SubjectPositionYEdit.Value, ...
                    app.SubjectPositionZEdit.Value]...
                );
            app.updateSubjectInfo();
        end

        function updateSubjectInfo(app)
            app.Subject.name = app.SubjectSelect.Value;

            f = app.SubjectFocalLength.Value;
            app.Subject.focal_length = f;

            pos = [app.SubjectPositionXEdit.Value,...
                    app.SubjectPositionYEdit.Value, ...
                    app.SubjectPositionZEdit.Value];
            app.Subject.position = pos;
            
            dim = app.Monitor.dimension;
            hangle = atan2(dim(1)/2+pos(1),pos(3))+atan2(dim(1)/2-pos(1),pos(3));
            vangle = atan2(dim(2)/2+pos(2),pos(3))+atan2(dim(2)/2-pos(2),pos(3));
            deg = pi/180;

            infotxt = sprintf('FOV: %.1f° × %.1f°\n         %.0f µm × %.0f µm (estimated)',...
                hangle/deg,vangle/deg,...
                f*hangle*1000,f*vangle*1000);
            app.Subject.info = infotxt;
            app.SubjectFOVInfo.Text = infotxt;

            try
                app.StimulusHandle.change();
            catch
            end
        end

        function updateSyncInfo(app)
            app.Sync.name = app.ScreenSync.Value;
        end

        function updateWorkdirInfo(app)
            app.ChangeWorkdirButton.Enable = true;
            app.StatusLabel.Text = sprintf("Path: %s", app.Workdir);
        end

        function statusMessage(app,text)
            if nargin < 2 || isempty(text)
                app.updateWorkdirInfo()
            else
                app.ChangeWorkdirButton.Enable = false;
                app.StatusLabel.Text = text;
            end
        end
    end
    
    methods (Access = public)
       
        function syncLoad(app,name)
            idx = find(strcmp({app.SyncModules.name},name));
            app.SyncHandle = rfstim.sync.(app.SyncModules(idx).module)(app);
            app.syncInit();
        end

        function setSubjectPosition(app,pos)
            app.Subject.position = pos;
            app.Config.set('subject.position',pos);

            app.SubjectCenteredCheckBox.Value = all(pos(1:2) == 0.0);
            app.SubjectCenteredCheckBoxValueChanged([]);

            app.SubjectPositionXEdit.Value = pos(1);
            app.SubjectPositionYEdit.Value = pos(2);
            app.SubjectPositionZEdit.Value = pos(3);
        end

        function close(app)
            try
                Screen('Close',app.WindowHandle);
            catch
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load config
            app.Config = appconfig(Program = 'matlab-rfstim', File = 'global.mat');

            % setup monitor
            % FIXME load from file
            app.updateMonitorInfo(app.MonitorSelect.Value);

            % setup screens
            screens = Screen('Screens');
            screenitems = cell(1,numel(screens));
            for i = 1:numel(screens)
                screenitems{i} = num2str(screens(i));
            end
            app.ScreenSelect.Items = screenitems;
            app.ScreenSelect.Value = app.Config.get('screen.id', screenitems{end});

            % setup sync
            appdir = mfilename('fullpath');
            [appdir,~,~] = fileparts(appdir);
            syncdir = fullfile(appdir, '+sync');

            modlist = rfstim.util.modules(syncdir,'sync');

            % setup subject
            % FIXME load from file
            app.loadSubjectInfo(app.SubjectSelect.Value);

            % subject position
            app.setSubjectPosition(app.Config.get('subject.position',[0 0 200]));

            % setup sync modules
            app.SyncModules = modlist;
            app.ScreenSync.Items = {app.SyncModules.name};

            app.ScreenSync.Value = app.Config.get('sync.name', app.ScreenSync.Items{1});
            app.syncLoad(app.ScreenSync.Value);

            % setup stimuli
            stimulidir = fullfile(appdir, '+stimuli');
            app.StimulusModules = rfstim.util.modules(stimulidir,'stimuli');
            app.StimulusSelect.Items = {app.StimulusModules.name};
            try
                app.StimulusSelect.Value = app.Config.get('stimulus.last', app.StimulusSelect.Items{1});
            catch
                app.StimulusSelect.Value = app.StimulusSelect.Items{1};
                app.Config.set('stimulus.last', app.StimulusSelect.Items{1});
            end
            app.StimulusSelectValueChanged([]);

            % workdir setup
            app.Workdir = app.Config.get('workdir', pwd);
            app.updateWorkdirInfo();
        end

        % Value changed function: ScreenSync
        function ScreenSyncValueChanged(app, event)
            value = app.ScreenSync.Value;

            if strcmp(value, app.SyncHandle.name())
                app.SyncHandle.startup(app.Config);
            else
                app.enableUI(false);
                app.ScreenSyncConnected.Color = [1.0,0.0,0.0];
                try
                    app.syncLoad(value)
                catch
                end
                app.enableUI(true);

                if ~app.SyncHandle.good()
                    app.ScreenSync.Value = app.ScreenSync.Items{1};
                end
            end

            app.updateSyncInfo();
        end

        % Value changed function: MonitorSelect
        function MonitorSelectValueChanged(app, event)
            app.updateMonitorInfo(app.MonitorSelect.Value);
        end

        % Button pushed function: MonitorAdd
        function MonitorAddButtonPushed(app, event)
            % FIXME
        end

        % Value changed function: ScreenSelect
        function ScreenSelectValueChanged(app, event)
            if ~isempty(app.WindowHandle)
                try 
                    delete(app.WindowHandle);
                    app.WindowHandle = [];
                catch
                end
            end

            value = app.ScreenSelect.Value;

            app.Config.set('screen.id', value);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ~isempty(app.WindowHandle)
                return
            end

            screeno = str2double(app.ScreenSelect.Value);

            app.enableUI(false);
            try
                [app.WindowHandle, app.WindowRect] = Screen('OpenWindow', screeno, [128 128 128]);
                app.ConnectedLamp.Color = [0.0,1.0,0.0];
            catch
                app.ConnectedLamp.Color = [1.0,0.0,0.0];
            end
            app.enableUI(true);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.close()
            delete(app)
        end

        % Value changed function: StimulusSelect
        function StimulusSelectValueChanged(app, event)
            value = app.StimulusSelect.Value;
            try 
                idx = find(strcmp({app.StimulusModules.name},value));
                app.StimulusHandle = rfstim.stimuli.(app.StimulusModules(idx).module)(app.StimulusPanel);
                app.StimulusHandle.startup(app);
                app.Config.set('stimulus.last', value);
            catch me
                disp(me)
                app.StimulusSelect.Value = app.Config.get('stimulus.last', app.StimulusSelect.Items{1});
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StimulusHandle.run()
        end

        % Callback function
        function PauseButtonPushed(app, event)
            app.StimulusHandle.pause()
        end

        % Value changed function: SubjectCenteredCheckBox
        function SubjectCenteredCheckBoxValueChanged(app, event)
            value = app.SubjectCenteredCheckBox.Value;
            if value
                app.SubjectPositionXEdit.Value = 0;
                app.SubjectPositionXEdit.Enable = false;
                app.SubjectPositionXEditValueChanged([]);
                app.SubjectPositionYEdit.Value = 0;
                app.SubjectPositionYEdit.Enable = false;
                app.SubjectPositionYEditValueChanged([]);
            else
                app.SubjectPositionXEdit.Enable = true;
                app.SubjectPositionYEdit.Enable = true;
            end
        end

        % Value changed function: SubjectPositionXEdit
        function SubjectPositionXEditValueChanged(app, event)
            app.updateSubjectInfo();
        end

        % Value changed function: SubjectPositionYEdit
        function SubjectPositionYEditValueChanged(app, event)
            app.updateSubjectInfo();
        end

        % Value changed function: SubjectFocalLength
        function SubjectFocalLengthValueChanged(app, event)
            app.updateSubjectInfo();
        end

        % Value changed function: SubjectPositionZEdit
        function SubjectPositionZEditValueChanged(app, event)
            app.updateSubjectInfo();
        end

        % Button pushed function: ChangeWorkdirButton
        function ChangeWorkdirButtonPushed(app, event)
            app.enableUI(false);
            reenableUI = onCleanup(@() app.enableUI(true));

            workdir = uigetdir(app.Workdir, 'Select working directory...');
            if ~ischar(workdir)
                return
            end
            app.Workdir = workdir;
            app.Config.set('workdir', workdir);

          app.updateWorkdirInfo()
        end

        % Value changed function: SubjectSelect
        function SubjectSelectValueChanged(app, event)
            app.loadSubjectInfo(app.SubjectSelect.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LayoutMain = uigridlayout(app.UIFigure);
            app.LayoutProperties = uigridlayout(app.LayoutMain);
            app.MonitorPanel = uipanel(app.LayoutProperties);
            app.MonitorPanel.Title = 'Monitor';
            app.UIAxes = uiaxes(app.MonitorPanel);
            xlabel(app.UIAxes, 'V (1)')
            ylabel(app.UIAxes, 'I (cd/m²)')
            zlabel(app.UIAxes, 'Z')
            app.MonitorSelect = uidropdown(app.MonitorPanel);
            app.MonitorSelect.Items = {'Alienware 27 - AW2725DF'};
            app.MonitorSelect.ValueChangedFcn = createCallbackFcn(app, @MonitorSelectValueChanged, true);
            app.MonitorSelect.Value = 'Alienware 27 - AW2725DF';
            app.MonitorAdd = uibutton(app.MonitorPanel, 'push');
            app.MonitorAdd.ButtonPushedFcn = createCallbackFcn(app, @MonitorAddButtonPushed, true);
            app.MonitorAdd.Enable = 'off';
            app.MonitorAdd.Text = 'Add...';
            app.XYmmEditFieldLabel = uilabel(app.MonitorPanel);
            app.XYmmEditFieldLabel.Text = 'X,Y (mm):';
            app.MonitorXmmEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorXmmEditField.Editable = 'off';
            app.MonitorXmmEditField.Value = 590.4;
            app.MonitorYmmEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorYmmEditField.Editable = 'off';
            app.MonitorYmmEditField.Value = 332.1;
            app.XYpxEditFieldLabel = uilabel(app.MonitorPanel);
            app.XYpxEditFieldLabel.Text = 'X,Y (px):';
            app.MonitorXpxEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorXpxEditField.Editable = 'off';
            app.MonitorXpxEditField.Value = 2560;
            app.MonitorYpxEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorYpxEditField.Editable = 'off';
            app.MonitorYpxEditField.Value = 1440;
            app.MonitorCalibrateButton = uibutton(app.MonitorPanel, 'push');
            app.MonitorCalibrateButton.Enable = 'off';
            app.MonitorCalibrateButton.Text = 'Calibration';
            app.ScreenDropDownLabel = uilabel(app.MonitorPanel);
            app.ScreenDropDownLabel.Text = 'Screen:';
            app.ScreenSelect = uidropdown(app.MonitorPanel);
            app.ScreenSelect.Items = {'''0''', '''1''', '''2'''};
            app.ScreenSelect.ValueChangedFcn = createCallbackFcn(app, @ScreenSelectValueChanged, true);
            app.ScreenSelect.Value = '''1''';
            app.ConnectButton = uibutton(app.MonitorPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectedLamp = uilamp(app.MonitorPanel);
            app.SubjectPanel = uipanel(app.LayoutProperties);
            app.SubjectPanel.Title = 'Subject';
            app.SubjectSelect = uidropdown(app.SubjectPanel);
            app.SubjectSelect.Items = {'Generic'};
            app.SubjectSelect.ValueChangedFcn = createCallbackFcn(app, @SubjectSelectValueChanged, true);
            app.SubjectSelect.Value = 'Generic';
            app.SubjectAdd = uibutton(app.SubjectPanel, 'push');
            app.SubjectAdd.Enable = 'off';
            app.SubjectAdd.Text = 'Add...';
            app.FocallengthmmLabel = uilabel(app.SubjectPanel);
            app.FocallengthmmLabel.Text = 'Focal length (mm):';
            app.SubjectFocalLength = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectFocalLength.Limits = [0 Inf];
            app.SubjectFocalLength.ValueDisplayFormat = '%.1f';
            app.SubjectFocalLength.ValueChangedFcn = createCallbackFcn(app, @SubjectFocalLengthValueChanged, true);
            app.SubjectFocalLength.Value = 2.6;
            app.SubjectPositionLabel = uilabel(app.SubjectPanel);
            app.SubjectPositionLabel.Text = 'Postion (relative to monitor center):';
            app.SubjectCenteredCheckBox = uicheckbox(app.SubjectPanel);
            app.SubjectCenteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubjectCenteredCheckBoxValueChanged, true);
            app.SubjectCenteredCheckBox.Text = 'centered';
            app.SubjectCenteredCheckBox.Value = true;
            app.XmmEditFieldLabel = uilabel(app.SubjectPanel);
            app.XmmEditFieldLabel.Text = 'X (mm):';
            app.SubjectPositionXEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionXEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionXEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectPositionXEditValueChanged, true);
            app.SubjectPositionXEdit.Enable = 'off';
            app.YmmLabel = uilabel(app.SubjectPanel);
            app.YmmLabel.Text = 'Y (mm):';
            app.SubjectPositionYEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionYEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionYEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectPositionYEditValueChanged, true);
            app.SubjectPositionYEdit.Enable = 'off';
            app.ZmmLabel = uilabel(app.SubjectPanel);
            app.ZmmLabel.Text = 'Z (mm):';
            app.SubjectPositionZEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionZEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionZEdit.ValueChangedFcn = createCallbackFcn(app, @SubjectPositionZEditValueChanged, true);
            app.SubjectPositionZEdit.Value = 200;
            app.SubjectFOVInfo = uilabel(app.SubjectPanel);
            app.SubjectFOVInfo.Text = {'FOV: 111.8° × 79.4°'; '         5072 µm × 3603 µm (estimated)'};
            app.SubjectInfo = uilabel(app.SubjectPanel);
            app.SubjectInfo.Text = {'Info'; '...'};
            app.SyncPanel = uipanel(app.LayoutProperties);
            app.SyncLabel = uilabel(app.SyncPanel);
            app.SyncLabel.Text = 'Sync:';
            app.ScreenSync = uidropdown(app.SyncPanel);
            app.ScreenSync.Items = {};
            app.ScreenSync.ValueChangedFcn = createCallbackFcn(app, @ScreenSyncValueChanged, true);
            app.ScreenSync.Value = {};
            app.ScreenSyncConnected = uilamp(app.SyncPanel);
            app.LayoutStimulus = uigridlayout(app.LayoutMain);
            app.StimulusPanel = uipanel(app.LayoutStimulus);
            app.StimulusSelectPanel = uipanel(app.LayoutStimulus);
            app.StimulusLabel = uilabel(app.StimulusSelectPanel);
            app.StimulusLabel.Text = 'Stimulus:';
            app.StimulusSelect = uidropdown(app.StimulusSelectPanel);
            app.StimulusSelect.Items = {};
            app.StimulusSelect.ValueChangedFcn = createCallbackFcn(app, @StimulusSelectValueChanged, true);
            app.StimulusSelect.Value = {};
            app.RunButton = uibutton(app.StimulusSelectPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'icons', 'media-playback-start.svg');
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.SaveLayout = uigridlayout(app.LayoutStimulus);
            app.ChangeWorkdirButton = uibutton(app.SaveLayout, 'push');
            app.ChangeWorkdirButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeWorkdirButtonPushed, true);
            app.ChangeWorkdirButton.Text = 'Change...';
            app.StatusLabel = uilabel(app.SaveLayout);
            app.StatusLabel.Text = 'Work directory: .';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oldapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end